classdef data_acquisition < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DataAcquisitionUIFigure  matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        GridLayout1              matlab.ui.container.GridLayout
        MethodPanel              matlab.ui.container.Panel
        GridLayout2              matlab.ui.container.GridLayout
        DropDown                 matlab.ui.control.DropDown
        SaveButton               matlab.ui.control.Button
        LoadButton               matlab.ui.control.Button
        LogBox                   matlab.ui.control.ListBox
        LogListBoxLabel          matlab.ui.control.Label
        ConnectPanel             matlab.ui.container.Panel
        GridLayout3              matlab.ui.container.GridLayout
        DevicesDropDown          matlab.ui.control.DropDown
        ConnectButton            matlab.ui.control.Button
        RefreshButton            matlab.ui.control.Button
        CenterPanel              matlab.ui.container.Panel
        RightPanel               matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    properties (Access = private)
        curSettings;
    end

    methods (Access = private)

        function log(app, message)
            message = string(datetime) + " " + message;
            if(isempty(app.LogBox.Items))
                app.LogBox.Items = cellstr(message);
            else
                app.LogBox.Items(end+1) = cellstr(message);
            end
            app.LogBox.Value = app.LogBox.Items(end);
        end

        function loadSDK(app)
            log(app, "Checking settings.mat");
            fType = exist("settings.mat", "file");
            if(fType == 2)
                log(app, "Loading settings.mat");
                load("settings.mat", "Settings");
                % Check whether settings contains the required field.
                if(isfield(Settings.Default, 'dllPath') == 1)
                    % Check whether the previously specified folder
                    % contains the PalmSens Core dll.
                    dllPath = Settings.Default.dllPath;
                    fType = exist(dllPath, 'file');
                    if(fType ~= 2)
                        fType = 0;
                    end
                else
                    fType = 0;
                end
            end
            % Prompt location of SDK library
            while(fType == 0)
                log(app, "Please specify the location of "...
                    + "PalmSens.Core.Matlab.dll");
                [dllName, dllFolder] =...
                    uigetfile("PalmSens.Core.Matlab.dll",...
                    "Please specify the location of "...
                    + "PalmSens.Core.Matlab.dll");
                % Check whether a file was selected.
                if(isempty(dllFolder))
                    log(app, "Error: no file selected.");
                    continue;
                end
                % Double check to determine if the selected file exists.
                dllPath = fullfile(dllFolder, dllName);
                fType = exist(dllPath, 'file');
                if(fType ~= 2)
                    fType = 0;
                end
                % Check to determine whether the correct
                % file was specified.
                if(dllName ~= "PalmSens.Core.Matlab.dll")
                    log(app, "Please select the PalmSens SDK "...
                        + "library named PalmSens.Core.Matlab.dll");
                    continue;
                end
            end
            % Store path of SDK library in settings.
            Settings.Default.dllPath = dllPath;
            if(exist('settings.mat', 'file') ~= 2)
                save('settings.mat', 'Settings');
            else
                save('settings.mat', 'Settings', '-append');
            end
            % Load PalmSens SDK library.
            Settings.PSSDK = NET.addAssembly(dllPath);
            log(app, "PalmSens SDK loaded succesfully.");
            % Update settings.
            app.curSettings = Settings;
            log(app, "PalmSens SDK location saved.");
        end

        function scanDevices(app)
            % Get connected devices.
            deviceList = getConnectedDevices(app);
            nDevices = deviceList.Count;
            log(app, num2str(nDevices) +...
                " device(s) connected:");
            devices = cell(nDevices);
            % Display connected devices.
            for ii = 0:nDevices-1
                log(app, num2str(ii+1) + " - " +...
                    char(deviceList.Item(ii).ToString()));
                devices{ii+1} = char(deviceList.Item(ii).ToString());
            end
            % Disable/enable connect button depending
            % on the presence of devices.
            if(nDevices == 0)
                app.ConnectButton.Enable = "off";
                devices = cellstr("No Devices Detected");
            else
                app.ConnectButton.Enable = "on";
            end
            % Update popup menu and store device information.
            app.DevicesDropDown.Items = devices;
            app.curSettings.Devices.deviceList = deviceList;
            app.curSettings.Devices.nDevices = nDevices;
        end

        function createMethod(app, technique)
            % Create a new method of the specified type.
            method = NewMethod(technique);
            UpdateMethodFields(app, method, technique);
        end

        function deviceList = getConnectedDevices(app,...
                bluetooth, serial)
            % GetConnectedDevices returns a list of all
            % the connected comm devices.
            % Optional Inputs:
            % - bluetooth: a boolean that when set
            %              to true enables scanning for bluetooth.
            %              Setting bluetooth to false
            %              is faster. (By default scanning
            %              for bluetooth is disabled)
            % - serial: a boolean that when set to true
            %           enables scanning for serial
            %           devices. (By default scanning
            %           for serial devices is disabled)
            % Output:
            % - deviceList: List objects <PalmSens.Devices.Device>
            %               representing the connected devices.

            % Check input parameters.
            if(nargin == 2)
                serial = false;
            end
            if(nargin == 1)
                serial = false;
                bluetooth = false;
            end
            % Create an empty device list.
            deviceList = NET.createGeneric(...
                'System.Collections.Generic.List',...
                {'PalmSens.Devices.Device'});
            % Create an error string, required as an input parameter.
            errors = System.String('');
            % Scan for connected devices.
            listUSBCDC = PalmSens.Windows.Devices...
                .USBCDCDevice.DiscoverDevicesMatlab(errors);
            listFTDI = PalmSens.Windows.Devices...
                .FTDIDevice.DiscoverDevices(errors);
            % Add devices to connected device list.
            deviceList.AddRange(listUSBCDC); % PalmSens 4
            deviceList.AddRange(listFTDI); % PalmSens 3 and Emstats
            % Scan for and add bluetooth devices
            % to connected device list.
            if(bluetooth == true)
                listBluetooth = PalmSens.Windows.Devices...
                    .BluetoothDevice.DiscoverDevices(errors);
                deviceList.AddRange(listBluetooth);
            end
            % Scan for and add serial devices
            % to connected device list.
            if(serial == true)
                listSerial = PalmSens.Windows.Devices...
                    .SerialPortDevice.DiscoverDevices(errors);
                deviceList.AddRange(listSerial);
            end
            % Remove duplicates from the connected device list.
            n = deviceList.Count - 1;
            i = 0;
            while(i <= n)
                j = i + 1;
                str = deviceList.Item(i).ToString();
                while(j <= n)
                    if(str == deviceList.Item(j).ToString())
                        deviceList.RemoveAt(j);
                        n = deviceList.Count - 1;
                    else
                        j = j + 1;
                    end
                end
                i = i + 1;
            end
        end

        function method = newMethod(app, technique)
            % newMethod instantiates a new method object
            % of the specified technique.
            % Input:
            % - technique: a number specifying the index
            %              of one of the following techniques.
            %     1.  Linear Sweep Voltammetry
            %     2.  Cyclic Voltammetry
            %     3.  AC Voltammetry
            %     4.  Differential Pulse Voltammetry
            %     5.  Square Wave Voltammetry
            %     6.  Normal Pulse Voltammetry
            %     7.  Chronoamperometry
            %     8.  MultiStep Amperometry
            %     9.  Fast Amperometry
            %     10. Pulsed Amperometric Detection
            %     11. Multiple Pulse Amperometry
            %     12. Open Circuit Potentiometry
            %     13. Chronopotentiometry
            %     14. MultiStep Potentiometry
            %     15. Chronopotentiometric Stripping
            %     16. Impedance Spectroscopy (PS3 and PS4 only)
            % Output:
            % - method: the respective tecnique's method
            %           object containing its parameters.
            %           (if an invalid method is specified
            %           this will return false)
            method = false;
            %Check whether a valid technique was specified.
            if(technique < 1 || technique > 17)
                return;
            end

            if(technique == 1)
                method = PalmSens.Techniques.LinearSweep();
            end
            if(technique == 2)
                method = PalmSens.Techniques.CyclicVoltammetry();
            end
            if(technique == 3)
                method = PalmSens.Techniques.ACVoltammetry();
            end
            if(technique == 4)
                method = PalmSens.Techniques.DifferentialPulse();
            end
            if(technique == 5)
                method = PalmSens.Techniques.SquareWave();
            end
            if(technique == 6)
                method = PalmSens.Techniques.NormalPulse();
            end
            if(technique == 7)
                method = PalmSens.Techniques.AmperometricDetection();
            end
            if(technique == 8)
                method = PalmSens.Techniques.MultistepAmperometry();
            end
            if(technique == 9)
                method = PalmSens.Techniques.FastAmperometry();
            end
            if(technique == 10)
                method = PalmSens.Techniques.PulsedAmpDetection();
            end
            if(technique == 11)
                method = PalmSens.Techniques.MultiplePulseAmperometry();
            end
            if(technique == 12)
                method = PalmSens.Techniques.OpenCircuitPotentiometry();
            end
            if(technique == 13)
                method = PalmSens.Techniques.Potentiometry();
            end
            if(technique == 14)
                method = PalmSens.Techniques.MultistepPotentiometry();
            end
            if(technique == 15)
                method = PalmSens.Techniques.ChronoPotStripping();
            end
            if(technique == 16)
                method = PalmSens.Techniques.ImpedimetricMethod();
            end
            if(technique == 17)
                method = PalmSens.Techniques.FastCyclicVoltammetry();

            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Reset LogBox
            app.LogBox.Items = {};
            % Load SDK
            loadSDK(app);
            % Scan devices
            scanDevices(app);
            % Initcialize methods
            createMethod(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.DataAcquisitionUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {600, 600, 600};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {600, 600};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {289, '1x', 172};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DataAcquisitionUIFigure = uifigure('Visible', 'off');
            app.DataAcquisitionUIFigure.Name = 'Data Acquisition';
            app.DataAcquisitionUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.DataAcquisitionUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout1 = uigridlayout(app.LeftPanel);
            app.ConnectPanel = uipanel(app.GridLayout1);
            app.ConnectPanel.Title = 'Connect to device';
            app.DevicesDropDown = uidropdown(app.GridLayout3);
            app.DevicesDropDown.Items = {};
            app.DevicesDropDown.Placeholder = 'Device name';
            app.DevicesDropDown.Value = {};
            app.ConnectButton = uibutton(app.GridLayout3, 'push');
            app.ConnectButton.Text = 'Connect';
            app.RefreshButton = uibutton(app.GridLayout3, 'push');
            app.RefreshButton.Text = 'Refresh';
            app.GridLayout3 = uigridlayout(app.ConnectPanel);
            app.RefreshButton = uibutton(app.GridLayout3, 'push');
            app.RefreshButton.Text = 'Refresh';
            app.RefreshButton = uibutton(app.GridLayout3, 'push');
            app.RefreshButton.Text = 'Refresh';
            app.RefreshButton = uibutton(app.GridLayout3, 'push');
            app.RefreshButton.Text = 'Refresh';
            app.RefreshButton = uibutton(app.GridLayout3, 'push');
            app.RefreshButton.Text = 'Refresh';
            app.RefreshButton = uibutton(app.GridLayout3, 'push');
            app.RefreshButton.Text = 'Refresh';
            app.RefreshButton = uibutton(app.GridLayout3, 'push');
            app.RefreshButton.Text = 'Refresh';
            app.RefreshButton = uibutton(app.GridLayout3, 'push');
            app.RefreshButton.Text = 'Refresh';
            app.ConnectButton = uibutton(app.GridLayout3, 'push');
            app.ConnectButton.Text = 'Connect';
            app.ConnectButton = uibutton(app.GridLayout3, 'push');
            app.ConnectButton.Text = 'Connect';
            app.ConnectButton = uibutton(app.GridLayout3, 'push');
            app.ConnectButton.Text = 'Connect';
            app.ConnectButton = uibutton(app.GridLayout3, 'push');
            app.ConnectButton.Text = 'Connect';
            app.ConnectButton = uibutton(app.GridLayout3, 'push');
            app.ConnectButton.Text = 'Connect';
            app.ConnectButton = uibutton(app.GridLayout3, 'push');
            app.ConnectButton.Text = 'Connect';
            app.ConnectButton = uibutton(app.GridLayout3, 'push');
            app.ConnectButton.Text = 'Connect';
            app.DevicesDropDown = uidropdown(app.GridLayout3);
            app.DevicesDropDown.Items = {};
            app.DevicesDropDown.Placeholder = 'Device name';
            app.DevicesDropDown.Value = {};
            app.DevicesDropDown = uidropdown(app.GridLayout3);
            app.DevicesDropDown.Items = {};
            app.DevicesDropDown.Placeholder = 'Device name';
            app.DevicesDropDown.Value = {};
            app.DevicesDropDown = uidropdown(app.GridLayout3);
            app.DevicesDropDown.Items = {};
            app.DevicesDropDown.Placeholder = 'Device name';
            app.DevicesDropDown.Value = {};
            app.DevicesDropDown = uidropdown(app.GridLayout3);
            app.DevicesDropDown.Items = {};
            app.DevicesDropDown.Placeholder = 'Device name';
            app.DevicesDropDown.Value = {};
            app.DevicesDropDown = uidropdown(app.GridLayout3);
            app.DevicesDropDown.Items = {};
            app.DevicesDropDown.Placeholder = 'Device name';
            app.DevicesDropDown.Value = {};
            app.DevicesDropDown = uidropdown(app.GridLayout3);
            app.DevicesDropDown.Items = {};
            app.DevicesDropDown.Placeholder = 'Device name';
            app.DevicesDropDown.Value = {};
            app.DevicesDropDown = uidropdown(app.GridLayout3);
            app.DevicesDropDown.Items = {};
            app.DevicesDropDown.Placeholder = 'Device name';
            app.DevicesDropDown.Value = {};
            app.LogListBoxLabel = uilabel(app.GridLayout1);
            app.LogListBoxLabel.Text = 'Log';
            app.LogBox = uilistbox(app.GridLayout1);
            app.MethodPanel = uipanel(app.GridLayout1);
            app.MethodPanel.Title = 'Method';
            app.GridLayout2 = uigridlayout(app.MethodPanel);
            app.LoadButton = uibutton(app.GridLayout2, 'push');
            app.LoadButton.Text = 'Load';
            app.SaveButton = uibutton(app.GridLayout2, 'push');
            app.SaveButton.Text = 'Save';
            app.DropDown = uidropdown(app.GridLayout2);
            app.CenterPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.DataAcquisitionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = data_acquisition

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.DataAcquisitionUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.DataAcquisitionUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DataAcquisitionUIFigure)
        end
    end
end